classdef adohe_fototoko < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        ToolsMenu                 matlab.ui.container.Menu
        HistogramMenu             matlab.ui.container.Menu
        BrigthnessMenu            matlab.ui.container.Menu
        NegativeMenu              matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutFototokoMenu         matlab.ui.container.Menu
        HelpMenu_2                matlab.ui.container.Menu
        Toolbar                   matlab.ui.container.Toolbar
        PushTool                  matlab.ui.container.toolbar.PushTool
        PushTool2                 matlab.ui.container.toolbar.PushTool
        PushTool3                 matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        OutputImageTab            matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        InputImage                matlab.ui.control.Image
        OutputImage               matlab.ui.control.Image
        HistogramTab_2            matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        OutputLabel               matlab.ui.control.Label
        InputLabel                matlab.ui.control.Label
        UIAxes6                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        TabGroup                  matlab.ui.container.TabGroup
        HistogramTab              matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        ChooseFIleButton          matlab.ui.control.Button
        NoFileSelectedLabel       matlab.ui.control.Label
        BrightnessTab             matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ApplyButton_2             matlab.ui.control.Button
        EnteradanbvaluesarbLabel  matlab.ui.control.Label
        BrightnessB               matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
        BrightnessA               matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        NegativeTab               matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        ApplyButton               matlab.ui.control.Button
        ProcessButton_2           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function openImage(app, event)
            [file,path,indx] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.bmp)'}, 'Select an Image File');
            if isequal(file,0)
               disp('User selected Cancel')
            else
               app.Fullimagename = fullfile(path, file);
               app.Inputimagedata = imread(app.Fullimagename);
               dm = ndims(app.Inputimagedata);
               if dm == 2
                   tmp = app.Inputimagedata(:,:);
                   app.Inputimagedata(:,:,1) = tmp;
                   app.Inputimagedata(:,:,2) = tmp;
                   app.Inputimagedata(:,:,3) = tmp;
               end
               app.Outputimagedata = app.Inputimagedata(:,:,:);

               app.InputImage.ImageSource = app.Inputimagedata;
               app.OutputImage.ImageSource = app.Outputimagedata;
            end
        end

        % Button pushed function: ProcessButton
        function processBrightness(app, event)
            sz = size(app.Inputimagedata);
            for i = 1:sz(1)
                for j = 1:sz(2)
                    for k = 1:sz(3)
                        s = app.BrightnessA.Value * app.Inputimagedata(i,j,k) + app.BrightnessB.Value;
                        if s > 255
                            s = 255;
                        elseif s < 0
                            s = 0;
                        end
                        app.Outputimagedata(i,j,k) = s;
                    end
                end
            end
            
            app.OutputImage.ImageSource = app.Outputimagedata;
        end

        % Button pushed function: ProcessButton_2
        function processNegative(app, event)
            app.Outputimagedata = 255 - app.Inputimagedata;
            app.OutputImage.ImageSource = app.Outputimagedata;
        end

        % Button pushed function: ApplyButton, ApplyButton_2
        function apply(app, event)
            app.Inputimagedata = app.Outputimagedata;
            app.InputImage.ImageSource = app.Inputimagedata;
        end

        % Button pushed function: ChooseFIleButton
        function ChooseFIleButtonPushed(app, event)
            [file,path] = uigetfile("*.*", 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end

            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, '');
            title(app.UIAxes2, '');
            title(app.UIAxes3, '');

            app.NoFileSelectedLabel.Text = file;
            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.Image component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
            
            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage, pngFilePath);
            
            % Display the PNG image in the uiimage component
            app.InputImage.ImageSource = pngFilePath;
            
            numChannels = size(bmpImage, 3);

            if numChannels == 3
                % Separate the RGB channels
                redChannel = bmpImage(:, :, 1);
                greenChannel = bmpImage(:, :, 2);
                blueChannel = bmpImage(:, :, 3);
        
                % Create histograms for each channel
                histogram(app.UIAxes, redChannel(:), 256, 'FaceColor', 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                histogram(app.UIAxes2, greenChannel(:), 256, 'FaceColor', 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                histogram(app.UIAxes3, blueChannel(:), 256, 'FaceColor', 'b'); % Blue channel histogram
    
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
    
                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');

            elseif numChannels == 1
                % Create a histogram for the grayscale image
                histogram(app.UIAxes, bmpImage(:), 256, 'FaceColor', 'k'); % Black (gray) channel histogram
        
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
            else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @openImage, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.HistogramMenu = uimenu(app.ToolsMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.BrigthnessMenu = uimenu(app.ToolsMenu);
            app.BrigthnessMenu.Text = 'Brigthness';
            app.NegativeMenu = uimenu(app.ToolsMenu);
            app.NegativeMenu.Text = 'Negative';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutFototokoMenu = uimenu(app.HelpMenu);
            app.AboutFototokoMenu.Text = 'About Fototoko..';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help...';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon_chart.svg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon_sun.svg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon_minus.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout6 = uigridlayout(app.HistogramTab);
            app.NoFileSelectedLabel = uilabel(app.GridLayout6);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.ChooseFIleButton = uibutton(app.GridLayout6, 'push');
            app.ChooseFIleButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFIleButtonPushed, true);
            app.ChooseFIleButton.Text = 'Choose FIle';
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Title = 'Brightness';
            app.GridLayout2 = uigridlayout(app.BrightnessTab);
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a';
            app.BrightnessA = uieditfield(app.GridLayout2, 'numeric');
            app.BrightnessA.Value = 1;
            app.ProcessButton = uibutton(app.GridLayout2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processBrightness, true);
            app.ProcessButton.Text = 'Process';
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b';
            app.BrightnessB = uieditfield(app.GridLayout2, 'numeric');
            app.EnteradanbvaluesarbLabel = uilabel(app.GridLayout2);
            app.EnteradanbvaluesarbLabel.Text = 'Enter a dan b value (s = ar + b)';
            app.ApplyButton_2 = uibutton(app.GridLayout2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_2.Text = 'Apply';
            app.NegativeTab = uitab(app.TabGroup);
            app.NegativeTab.Title = 'Negative';
            app.GridLayout3 = uigridlayout(app.NegativeTab);
            app.ProcessButton_2 = uibutton(app.GridLayout3, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @processNegative, true);
            app.ProcessButton_2.Text = 'Process';
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.HistogramTab_2 = uitab(app.TabGroup2);
            app.HistogramTab_2.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.InputLabel = uilabel(app.GridLayout4);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout4);
            app.OutputLabel.Text = 'Output';
            app.OutputImageTab = uitab(app.TabGroup2);
            app.OutputImageTab.Title = 'Output Image';
            app.GridLayout5 = uigridlayout(app.OutputImageTab);
            app.OutputImage = uiimage(app.GridLayout5);
            app.InputImage = uiimage(app.GridLayout5);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end